classdef updated_bird_simu_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        vx_box                     matlab.ui.control.Spinner
        betaelevationangleLabel_2  matlab.ui.control.Label
        vz_box                     matlab.ui.control.Spinner
        vy_box                     matlab.ui.control.Spinner
        radarloc1_box              matlab.ui.control.Spinner
        betaelevationangleLabel    matlab.ui.control.Label
        radarloc3_box              matlab.ui.control.Spinner
        radarloc2_box              matlab.ui.control.Spinner
        nt_box_19                  matlab.ui.control.Spinner
        timedurationLabel_20       matlab.ui.control.Label
        nt_box_18                  matlab.ui.control.Spinner
        timedurationLabel_19       matlab.ui.control.Label
        nt_box_17                  matlab.ui.control.Spinner
        timedurationLabel_18       matlab.ui.control.Label
        nt_box_16                  matlab.ui.control.Spinner
        timedurationLabel_17       matlab.ui.control.Label
        nt_box_15                  matlab.ui.control.Spinner
        timedurationLabel_16       matlab.ui.control.Label
        nt_box_14                  matlab.ui.control.Spinner
        timedurationLabel_15       matlab.ui.control.Label
        nt_box_13                  matlab.ui.control.Spinner
        timedurationLabel_14       matlab.ui.control.Label
        nt_box_12                  matlab.ui.control.Spinner
        timedurationLabel_13       matlab.ui.control.Label
        nt_box_11                  matlab.ui.control.Spinner
        timedurationLabel_12       matlab.ui.control.Label
        nt_box_10                  matlab.ui.control.Spinner
        timedurationLabel_11       matlab.ui.control.Label
        nt_box_9                   matlab.ui.control.Spinner
        timedurationLabel_10       matlab.ui.control.Label
        nt_box_8                   matlab.ui.control.Spinner
        timedurationLabel_9        matlab.ui.control.Label
        nt_box_7                   matlab.ui.control.Spinner
        timedurationLabel_8        matlab.ui.control.Label
        nt_box_6                   matlab.ui.control.Spinner
        timedurationLabel_7        matlab.ui.control.Label
        nt_box_5                   matlab.ui.control.Spinner
        timedurationLabel_6        matlab.ui.control.Label
        nt_box_3                   matlab.ui.control.Spinner
        timedurationLabel_4        matlab.ui.control.Label
        nt_box_2                   matlab.ui.control.Spinner
        timedurationLabel_3        matlab.ui.control.Label
        nt_box                     matlab.ui.control.Spinner
        timedurationLabel_2        matlab.ui.control.Label
        log_box                    matlab.ui.control.TextArea
        RealtimelogsLabel          matlab.ui.control.Label
        T_box                      matlab.ui.control.Spinner
        timedurationLabel          matlab.ui.control.Label
        clearlogsButton            matlab.ui.control.Button
        drawmicroDopplersignatureButton  matlab.ui.control.Button
        drawRangeProfilesButton    matlab.ui.control.Button
        drawRadarTrackingaFlyingBirdButton  matlab.ui.control.Button
    end

    
    %% 公共属性
    properties (Access = private)
        j = sqrt(-1);
        
        % radar paramerters
        % time smapling
        T = 1; % time duration
        nt = 15000; % number of samples
        % dt = T/nt; % time interval
        % ts = [0:dt:T-dt]; % time span
        dt = [];
        ts = [];

        lambda = 0.0032;   % wavelength of transmitted radar signal
        c = 2.99792458e8;
        % f0 = c/lambda;
        f0 = [];
        rangeres = 0.05;  % designed range resolution
        radarloc = [20, 0, -10];  % radar location
        % total number of range bins
        % nr = floor(2*sqrt(radarloc(1)^2+radarloc(2)^2+radarloc(3)^2)/rangeres);
        nr = [];

        % t = ts;
        t = [];
        F0 = 5.0; % flapping frequency
        V = 0.0; % forward translation velocity
        A1 = 40; % amplitude of flapping angel in degree
        ksi10 = 15; % lag flapping angle in degree
        L1 = 0.5; % length of segment 1
        % ksi1 = A1*cos(2*pi*F0*t)+ksi10; % flapping angle
        ksi1 = [];

        % % the elbow joint position in local coordinates
        % x1 = V.*t;
        % y1 = L1*cosd(ksi1);
        % z1 = y1.*tand(ksi1);
        x1 = [];
        y1 = [];
        z1 = [];
    
        
        % segment 2 (forearm)
        A2 = 30; % amplitude of segment2 flapping angle
        ksi20 = 40; % lag flapping angle in degree
        L2 = 0.5; % length of segment 2
        C2 = 20; % amplitude of segment2 twisting angle
        % ksi2 = A2*cos(2*pi*F0*t)+ksi20;
        % theta2 = C2*sin(2*pi*F0*t);
        % % the wrist joint position in local coordinates
        % d = theta2./cosd(ksi1-ksi2);
        % y2 = L1*cosd(ksi1)+L2*cosd(theta2).*cosd(ksi1-ksi2);
        % x2 = x1-(y2-y1).*tand(d);
        % z2 = z1+(y2-y1).*tand(ksi1-ksi2);
        
        % % bird body position
        % Pb1(1,:) = -0.4+x1(:);
        % Pb1(2,:) = zeros(size(x1));
        % Pb1(3,:) = zeros(size(x1));
        % Pb2(1,:) = 0.4+x1(:);
        % Pb2(2,:) = zeros(size(x1));
        % Pb2(3,:) = zeros(size(x1));
        % 
        % % body center point
        % Cb = (Pb1+Pb2)/2;
        % 

        ksi2 = [];
        theta2 = [];
        d = [];
        y2 = [];
        x2 = [];
        z2 = [];
        
        % bird body position
        Pb1 = [];
        Pb2 = [];
        Cb = [];


        % % left upper arm position
        % Pua11 = Cb;
        % Pua21 = [x1; y1; z1];
        % Puac1 = (Pua11+Pua21)/2; % upper arm center 
        % 
        % % left forearm position
        % Pfa11 = Pua21;
        % Pfa21 = [x2; y2; z2];
        % Pfac1 = (Pfa11+Pfa21)/2; % forearm center
        % 
        % % right upper arm position
        % Pua12 = Cb;
        % Pua22 = [x1; -y1; z1];
        % Puac2 = (Pua12+Pua22)/2; % upper arm center 
        % 
        % % right forearm position
        % Pfa12 = Pua22;
        % Pfa22 = [x2; y2; z2];
        % Pfac2 = (Pfa12+Pfa22)/2; % forearm center
        
        % left upper arm position
        Pua11 = [];
        Pua21 = [];
        Puac1 = []; % upper arm center 

        % left forearm position
        Pfa11 = [];
        Pfa21 = [];
        Pfac1 = []; % forearm center

        % right upper arm position
        Pua12 = [];
        Pua22 = [];
        Puac2 = []; % upper arm center 

        % right forearm position
        Pfa12 = [];
        Pfa22 = [];
        Pfac2 = []; % forearm center


        % ellipsoid parameter
        % body
        Ba = 0.1;
        Bb = 0.1;
        Bc = 1.0;
        % wing
        Wa = 0.05; 
        Wb = 0.05; 
        Wc = 0.25;


        data = [];

        %% 新定义的变量
        r_dist = [];
        distances = [];
        aspct = [];
        ThetaAngle = [];
        PhiAngle = [];
        rcs = [];
        amp = [];

        %% 速度
        vx = 1; % forward translation velocity
        vy = 0;
        vz = 0;

    end
    
    methods (Access = private)
        

        %% 函数stft
        function TF = stft(app,f,wd)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % short-time Fourier transform with Guassian window
            %
            % Input:
            %      f - signal (real or complex)
            %      wd - std. deviation (sigma) of the Gaussian function
            % Output:
            %      TF - time-frequency distribution
            %
            % By V.C. Chen
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % f = app.f;
            % wd = app.wd;

            cntr = length(f)/2;
            sigma = wd;
            fsz = length(f);
            z = exp(-([1:fsz]-fsz/2).^2/(2*sigma^2))/(sqrt(2*pi)*sigma);
            for m=1:fsz
                mm = m-cntr+fsz/2;
                if (mm <= fsz & mm >= 1)
                    gwin(m) = z(mm);
                else
                    if (mm > fsz)
                       gwin(m) = z(rem(mm,fsz));
                    else
                       if(mm < 1)
                          mm1 = mm+fsz;
                          gwin(m) = z(mm1);
                       end
                    end
                end
            end
            winsz = length(gwin);
            x = zeros(1,fsz+winsz); % zero padding
            x(winsz/2+1:winsz/2+fsz) = f;
            for j = 1:fsz
               for k = 1:winsz
                  X(k) = gwin(k)*x(j+k);  
               end             
               TF(:,j) = fft(X).';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
        end


        function results = myfft(app, x)
            results = fft(x);
            
        end


        %% 函数 rcsellipsoid
        function rcs = rcsellipsoid(app, a,b,c,phi,theta)

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                % This program calculates the backscattered RCS of an ellipsoid
                % with semi-axis lengths of a, b, and c.
                % The source code is based on Radar Systems Analysis and Design Using
                % MATLAB, By B. Mahafza, Chapman & Hall/CRC 2000.
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                rcs = (pi*a^2*b^2*c^2)/(a^2*(sin(theta))^2*(cos(phi))^2+...
                    b^2*(sin(theta))^2*(sin(phi))^2+c^2*(cos(theta))^2)^2;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        end


        %% 函数ellipsoid2P
        
        function [X,Y,Z] = ellipsoid2P(app,P1,P2,a,b,c,N)
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                % Construct an ellipsoid connecting two center points: P1 and P2.
                % Semi-axis lengths are a, b, and c.
                % N is the number of grid points for plotting the ellipsoid. 
                % 
                % By V.C. Chen
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                Cntr = (P1+P2)/2;  % ellipsoid center
                Lc = norm(P2-P1);
                
                % the axis defined by: P1+V*[0:Lc]
                V = (P1-P2)/Lc;   %normalized cylinder's axis-vector;
                U = rand(1,3);     %linear independent vector
                U = V-U/(U*V');    %orthogonal vector to V
                U = U/sqrt(U*U');  %orthonormal vector to V
                W = cross(V,U);    %vector orthonormal to V and U
                W = W/sqrt(W*W');  %orthonormal vector to V and U 
                
                % generate the ellipsoid at (0,0,0)
                [Xc,Yc,Zc] = ellipsoid(0,0,0,a,b,c,N);
                
                A = kron(U',Xc);
                B = kron(W',Yc);
                C = kron(V',Zc);
                TMP = A+B+C;
                nt = size(TMP,2);
                
                X = TMP(1:nt,:)+Cntr(1);
                Y = TMP(nt+1:2*nt,:)+Cntr(2);
                Z = TMP(2*nt+1:end,:)+Cntr(3);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        end

        %% test fun
        function result = updata_wangxing(app)
            app.wangxing = 1;
            
        end
        

        %% 计算目标主体位置
        function results = cal_body_position(app)
 
                app.dt = app.T/app.nt; % time interval
                app.ts = [0:app.dt:app.T-app.dt]; % time span
                app.f0 = app.c/app.lambda;
                app.nr = floor(2*sqrt(app.radarloc(1)^2+app.radarloc(2)^2+app.radarloc(3)^2)/app.rangeres);
                
                app.t = app.ts;
                app.ksi1 = app.A1*cos(2*pi*app.F0*app.t)+app.ksi10; % flapping angle
                
                % the elbow joint position in local coordinates
                app.x1 = app.V.*app.t;
                app.y1 = app.L1*cosd(app.ksi1);
                app.z1 = app.y1.*tand(app.ksi1);
                
                app.ksi2 = app.A2*cos(2*pi*app.F0*app.t)+app.ksi20;
                app.theta2 = app.C2*sin(2*pi*app.F0*app.t);
                % the wrist joint position in local coordinates
                app.d = app.theta2./cosd(app.ksi1-app.ksi2);
                app.y2 = app.L1*cosd(app.ksi1)+app.L2*cosd(app.theta2).*cosd(app.ksi1-app.ksi2);
                app.x2 = app.x1-(app.y2-app.y1).*tand(app.d);
                app.z2 = app.z1+(app.y2-app.y1).*tand(app.ksi1-app.ksi2);
                
                % bird body position
                app.Pb1(1,:) = -0.4+app.x1(:);
                app.Pb1(2,:) = zeros(size(app.x1));
                app.Pb1(3,:) = zeros(size(app.x1));
                app.Pb2(1,:) = 0.4+app.x1(:);
                app.Pb2(2,:) = zeros(size(app.x1));
                app.Pb2(3,:) = zeros(size(app.x1));
                
                % body center point
                app.Cb = (app.Pb1+app.Pb2)/2;


        end


        %%  计算雷达数据  
        function results = cal_data(app)
                % 平动
                % 先旋转再平动 x1, y1, z1
                [app.x1, app.y1, app.z1] = app.rotate_and_translate(app.x1, app.y1, app.z1, app.vx, app.vy, app.vz, app.t);
                
                % 先旋转再平动 x2, y2, z2
                [app.x2, app.y2, app.z2] = app.rotate_and_translate(app.x2, app.y2, app.z2, app.vx, app.vy, app.vz, app.t);
                
                % 先旋转再平动 Pb1
                [app.Pb1(1,:), app.Pb1(2,:), app.Pb1(3,:)] = app.rotate_and_translate(app.Pb1(1,:), app.Pb1(2,:), app.Pb1(3,:), app.vx, app.vy, app.vz, app.t);
                
                % 先旋转再平动 Pb2
                [app.Pb2(1,:), app.Pb2(2,:), app.Pb2(3,:)] = app.rotate_and_translate(app.Pb2(1,:), app.Pb2(2,:), app.Pb2(3,:), app.vx, app.vy, app.vz, app.t);
                
                % body center point
                app.Cb = (app.Pb1 + app.Pb2) / 2;
                
                % left upper arm position
                app.Pua11 = app.Cb;
                app.Pua21 = [app.x1; app.y1; app.z1];
                app.Puac1 = (app.Pua11+app.Pua21)/2; % upper arm center 
                
                % left forearm position
                app.Pfa11 = app.Pua21;
                app.Pfa21 = [app.x2; app.y2; app.z2];
                app.Pfac1 = (app.Pfa11+app.Pfa21)/2; % forearm center
                
                % right upper arm position
                app.Pua12 = app.Cb;
                app.Pua22 = [app.x1; -app.y1; app.z1];
                app.Puac2 = (app.Pua12+app.Pua22)/2; % upper arm center 
                
                % right forearm position
                app.Pfa12 = app.Pua22;
                app.Pfa22 = [app.x2; app.y2; app.z2];
                app.Pfac2 = (app.Pfa12+app.Pfa22)/2; % forearm center

                
                % radar returns
                % prepare data collection
                app.data = zeros(app.nr,app.nt);
                
                % radar returns from the body
                for k = 1:app.nt
                    % distance from radar to bird body
                    app.r_dist(:,k) = abs(app.Cb(:,k)-app.radarloc(:));
                    app.distances(k) = sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2+app.r_dist(3,k).^2);
                    % aspect vector of the body
                    app.aspct(:,k) = app.Pb2(:,k)-app.Pb1(:,k);
                    % calculate theta angle
                    A = [app.radarloc(1)-app.Cb(1,k); app.radarloc(2)-app.Cb(2,k);...
                         app.radarloc(3)-app.Cb(3,k)];
                    B = [app.aspct(1,k); app.aspct(2,k); app.aspct(3,k)]; 
                    A_dot_B = dot(A,B,1);
                    A_sum_sqrt = sqrt(sum(A.*A,1));
                    B_sum_sqrt = sqrt(sum(B.*B,1));    
                    app.ThetaAngle(k) = acos(A_dot_B ./ (A_sum_sqrt .* B_sum_sqrt));
                    app.PhiAngle(k) = asin((app.radarloc(2)-app.Cb(2,k))./...
                                            sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2));       
                    app.rcs(k) = app.rcsellipsoid(app.Ba,app.Bb,app.Bc,app.PhiAngle(k),app.ThetaAngle(k));
                    app.amp(k) = sqrt(app.rcs(k));
                    PHs = app.amp(k)*(exp(-j*4*pi*app.distances(k)/app.lambda));   
                    app.data(floor(app.distances(k)/app.rangeres),k) = ...
                        app.data(floor(app.distances(k)/app.rangeres),k) + PHs;  
                end

                % radar returns from the left upper arm wing
                for k = 1:app.nt
                    % distance from radar to bird left upper arm
                    app.r_dist(:,k) = abs(app.Puac1(:,k)-app.radarloc(:));
                    app.distances(k) = sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2+app.r_dist(3,k).^2);
                    % aspect vector of the body
                    app.aspct(:,k) = app.Pua21(:,k)-app.Pua11(:,k);
                    % calculate theta angle
                    A = [app.radarloc(1)-app.Puac1(1,k); app.radarloc(2)-app.Puac1(2,k);...
                         app.radarloc(3)-app.Puac1(3,k)];
                    B = [app.aspct(1,k); app.aspct(2,k); app.aspct(3,k)]; 
                    A_dot_B = dot(A,B,1);
                    A_sum_sqrt = sqrt(sum(A.*A,1));
                    B_sum_sqrt = sqrt(sum(B.*B,1));    
                    app.ThetaAngle(k) = acos(A_dot_B ./ (A_sum_sqrt .* B_sum_sqrt));
                    app.PhiAngle(k) = asin((app.radarloc(2)-app.Puac1(2,k))./...
                                            sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2));       
                    app.rcs(k) = app.rcsellipsoid(app.Wa,app.Wb,app.Wc,app.PhiAngle(k),app.ThetaAngle(k));
                    app.amp(k) = sqrt(app.rcs(k));
                    PHs = app.amp(k)*(exp(-j*4*pi*app.distances(k)/app.lambda));   
                    app.data(floor(app.distances(k)/app.rangeres),k) = ...
                        app.data(floor(app.distances(k)/app.rangeres),k) + PHs;  
                end

                % radar returns from the left forearm wing
                for k = 1:app.nt
                    % distance from radar to bird body
                    app.r_dist(:,k) = abs(app.Pfac1(:,k)-app.radarloc(:));
                    app.distances(k) = sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2+app.r_dist(3,k).^2);
                    % aspect vector of the body
                    app.aspct(:,k) = app.Pfa21(:,k)-app.Pfa11(:,k);
                    % calculate theta angle
                    A = [app.radarloc(1)-app.Pfac1(1,k); app.radarloc(2)-app.Pfac1(2,k);...
                         app.radarloc(3)-app.Pfac1(3,k)];
                    B = [app.aspct(1,k); app.aspct(2,k); app.aspct(3,k)]; 
                    A_dot_B = dot(A,B,1);
                    A_sum_sqrt = sqrt(sum(A.*A,1));
                    B_sum_sqrt = sqrt(sum(B.*B,1));    
                    app.ThetaAngle(k) = acos(A_dot_B ./ (A_sum_sqrt .* B_sum_sqrt));
                    app.PhiAngle(k) = asin((app.radarloc(2)-app.Pfac1(2,k))./...
                                            sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2));       
                    app.rcs(k) = app.rcsellipsoid(app.Wa,app.Wb,app.Wc,app.PhiAngle(k),app.ThetaAngle(k));
                    app.amp(k) = sqrt(app.rcs(k));
                    PHs = app.amp(k)*(exp(-j*4*pi*app.distances(k)/app.lambda));   
                    app.data(floor(app.distances(k)/app.rangeres),k) = ...
                        app.data(floor(app.distances(k)/app.rangeres),k) + PHs;  
                end

                % radar returns from the right upper arm wing
                for k = 1:app.nt
                    % distance from radar to bird body
                    app.r_dist(:,k) = abs(app.Puac2(:,k)-app.radarloc(:));
                    app.distances(k) = sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2+app.r_dist(3,k).^2);
                    % aspect vector of the body
                    app.aspct(:,k) = app.Pua22(:,k)-app.Pua12(:,k);
                    % calculate theta angle
                    A = [app.radarloc(1)-app.Puac2(1,k); app.radarloc(2)-app.Pua21(2,k);...
                         app.radarloc(3)-app.Puac2(3,k)];
                    B = [app.aspct(1,k); app.aspct(2,k); app.aspct(3,k)]; 
                    A_dot_B = dot(A,B,1);
                    A_sum_sqrt = sqrt(sum(A.*A,1));
                    B_sum_sqrt = sqrt(sum(B.*B,1));    
                    app.ThetaAngle(k) = acos(A_dot_B ./ (A_sum_sqrt .* B_sum_sqrt));
                    app.PhiAngle(k) = asin((app.radarloc(2)-app.Puac2(2,k))./...
                                            sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2));       
                    app.rcs(k) = app.rcsellipsoid(app.Wa,app.Wb,app.Wc,app.PhiAngle(k),app.ThetaAngle(k));
                    app.amp(k) = sqrt(app.rcs(k));
                    PHs = app.amp(k)*(exp(-j*4*pi*app.distances(k)/app.lambda));   
                    app.data(floor(app.distances(k)/app.rangeres),k) = ...
                        app.data(floor(app.distances(k)/app.rangeres),k) + PHs;  
                end

                % radar returns from the right forearm wing
                for k = 1:app.nt
                    % distance from radar to bird body
                    app.r_dist(:,k) = abs(app.Pfac2(:,k)-app.radarloc(:));
                    app.distances(k) = sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2+app.r_dist(3,k).^2);
                    % aspect vector of the body
                    app.aspct(:,k) = app.Pfa22(:,k)-app.Pfa12(:,k);
                    % calculate theta angle
                    A = [app.radarloc(1)-app.Pfac2(1,k); app.radarloc(2)-app.Pfac2(2,k);...
                         app.radarloc(3)-app.Pfac2(3,k)];
                    B = [app.aspct(1,k); app.aspct(2,k); app.aspct(3,k)]; 
                    A_dot_B = dot(A,B,1);
                    A_sum_sqrt = sqrt(sum(A.*A,1));
                    B_sum_sqrt = sqrt(sum(B.*B,1));    
                    app.ThetaAngle(k) = acos(A_dot_B ./ (A_sum_sqrt .* B_sum_sqrt));
                    app.PhiAngle(k) = asin((app.radarloc(2)-app.Pfac2(2,k))./...
                                            sqrt(app.r_dist(1,k).^2+app.r_dist(2,k).^2));       
                    app.rcs(k) = app.rcsellipsoid(app.Wa,app.Wb,app.Wc,app.PhiAngle(k),app.ThetaAngle(k));
                    app.amp(k) = sqrt(app.rcs(k));
                    PHs = app.amp(k)*(exp(-j*4*pi*app.distances(k)/app.lambda));   
                    app.data(floor(app.distances(k)/app.rangeres),k) = ...
                        app.data(floor(app.distances(k)/app.rangeres),k) + PHs;  
                end

            
        end

        
        function results = updata_radarloc(app)
            app.radarloc = [app.radarloc1_box.Value, app.radarloc2_box.Value, app.radarloc3_box.Value];
        end


        function [x_new, y_new, z_new] = rotate_and_translate(app, x, y, z, vx, vy, vz, t)
            % 定义速度向量
            v = [vx; vy; vz];
        
            % 计算速度向量的单位向量
            v_unit = v / norm(v);
        
            % 初始方向向量（x正方向）
            initial_direction = [1; 0; 0];
        
            % 计算旋转轴（初始方向和速度方向的叉积）
            rotation_axis = cross(initial_direction, v_unit);
            rotation_axis = rotation_axis / norm(rotation_axis); % 单位化
        
            % 检查速度向量是否与初始方向向量一致
            if all(v_unit == initial_direction)
                % 如果一致，不进行旋转，只进行平动
                x_new = x + vx * t;
                y_new = y + vy * t;
                z_new = z + vz * t;
                return;
            end
        
            % 计算旋转角度（初始方向和速度方向的点积）
            theta = acos(dot(initial_direction, v_unit));
        
            % 使用 Rodrigues' 旋转公式计算旋转矩阵
            K = [0 -rotation_axis(3) rotation_axis(2);
                 rotation_axis(3) 0 -rotation_axis(1);
                 -rotation_axis(2) rotation_axis(1) 0];
            R = eye(3) + sin(theta) * K + (1 - cos(theta)) * (K * K);
        
            % 获取矩阵的大小
            [m, n] = size(x);
        
            % 初始化新的坐标矩阵
            x_new = zeros(m, n);
            y_new = zeros(m, n);
            z_new = zeros(m, n);
        
            % 对每个点应用旋转
            for ii = 1:m
                for jj = 1:n
                    % 计算新的位置
                    new_position = R * [x(ii, jj); y(ii, jj); z(ii, jj)];
                    % 更新x, y, z
                    x_new(ii, jj) = new_position(1);
                    y_new(ii, jj) = new_position(2);
                    z_new(ii, jj) = new_position(3);
                end
            end
        
            % 平动
            x_new = x_new + vx * t;
            y_new = y_new + vy * t;
            z_new = z_new + vz * t;
        end
                end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updata_radarloc();
            app.UIFigure.Icon = 'radar.png';
            app.UIFigure.Name = 'Bird Radar Detection Simulator';
        end

        % Value changed function: T_box
        function T_boxValueChanged(app, event)
                app.T = app.T_box.Value;
        end

        % Button pushed function: drawRadarTrackingaFlyingBirdButton
        function drawRadarTrackingaFlyingBirdButtonPushed(app, event)
            app.log_box.Value="calculate body position ";
            app.cal_body_position()
            app.log_box.Value=[app.log_box.Value; "draw body position "];
            figure(1)
            for k = 1:200:app.nt
                clf
                hold on
                colormap([0.7 0.7 0.7])    
                [x,y,z] = app.ellipsoid2P([-0.4+app.x1(k),0,0],[0.4+app.x1(k),0,0],0.1,0.1,0.4,30);
                [x, y, z] = app.rotate_and_translate(x, y, z, app.vx, app.vy, app.vz, app.t(k));
                surf(x,y,z) % 
                % left wing
                [x,y,z] = app.ellipsoid2P([app.x1(k),0,0],[app.x1(k),app.y1(k),app.z1(k)],...
                                      0.05,0.05,0.25,30);
                [x, y, z] = app.rotate_and_translate(x, y, z, app.vx, app.vy, app.vz, app.t(k));
                surf(x,y,z) % 
                [x,y,z] = app.ellipsoid2P([app.x1(k),app.y1(k),app.z1(k)],[app.x2(k),app.y2(k),app.z2(k)],...
                                      0.05,0.05,0.25,30);
                 [x, y, z] = app.rotate_and_translate(x, y, z, app.vx, app.vy, app.vz, app.t(k));
                surf(x,y,z) 
                % right wing
                [x,y,z] = app.ellipsoid2P([app.x1(k),0,0],[app.x1(k),-app.y1(k),app.z1(k)],...
                                      0.05,0.05,0.25,30);
                 [x, y, z] = app.rotate_and_translate(x, y, z, app.vx, app.vy, app.vz, app.t(k));
                surf(x,y,z) % 
                [x,y,z] = app.ellipsoid2P([app.x1(k),-app.y1(k),app.z1(k)],[app.x2(k),-app.y2(k),app.z2(k)],...
                                     0.05,0.05,0.25,30);
                [x, y, z] = app.rotate_and_translate(x, y, z, app.vx, app.vy, app.vz, app.t(k));
                surf(x,y,z)
                light
                lighting gouraud
                light('Position',[20 10 20],'Style','infinite');
                shading interp
                axis equal
                axis([-1,20,-5,5,-10,2])
                axis on
                grid on
                set(gcf,'Color',[1 1 1])
                view([30,15])
                % draw radar location
                plot3(app.radarloc(1),app.radarloc(2),app.radarloc(3),'-ro',...
                             'LineWidth',2,...
                             'MarkerEdgeColor','r',...
                             'MarkerFaceColor','y',...
                             'MarkerSize',10)
                % draw a line from radar to the target center
                app.Cb(1,k) = app.Cb(1,k)+app.vx * app.t(k);
                app.Cb(2,k) = app.Cb(2,k)+app.vy * app.t(k);
                app.Cb(3,k) = app.Cb(3,k)+app.vz * app.t(k);
                line([app.radarloc(1) app.Cb(1,k)],[app.radarloc(2) app.Cb(2,k)],...
                    [app.radarloc(3) app.Cb(3,k)],...
                    'color',[0.4 0.7 0.7],'LineWidth',1.5,'LineStyle','-.')
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
                title('Radar Tracking a Flying Bird')
                drawnow
            
            end
            app.log_box.Value=[app.log_box.Value; "finish!"];
        end

        % Button pushed function: drawRangeProfilesButton
        function drawRangeProfilesButtonPushed(app, event)
                  
                 app.log_box.Value = "";
                 content = 'Calculate body position';
                 app.log_box.Value = [app.log_box.Value; content];
                 app.cal_body_position()
                 
                 content = 'Calculate data';
                 app.log_box.Value = [app.log_box.Value; content];
                 app.cal_data()
                
                
                app.log_box.Value=[app.log_box.Value; "draw Range Profiles "];
                figure(2)
                colormap(jet(256))
                imagesc([1,app.nt],[0,app.nr*app.rangeres],20*log10(abs(app.data)+eps))
                xlabel('Pulses')
                ylabel('Range (m)')
                title('Range Profiles of Bird Flapping Wings')
                axis xy
                clim = get(gca,'CLim');
                set(gca,'CLim',clim(2) + [-20 10]);
                colorbar
                drawnow

                app.log_box.Value=[app.log_box.Value; "finish!"];
        end

        % Button pushed function: drawmicroDopplersignatureButton
        function drawmicroDopplersignatureButtonPushed(app, event)
                app.log_box.Value = "";
                app.cal_body_position()
                content = 'Calculate body position';
                app.log_box.Value = [app.log_box.Value; content];

                app.cal_data()
                content = 'Calculate data';
                app.log_box.Value = [app.log_box.Value; content];

                % micro-Doppler signature
                x = sum(app.data);
                np = app.nt;
                
                dT = app.T/length(app.ts);
                F = 1/dT;
                dF = 1/app.T;
                
                wd = 512;
                wdd2 = wd/2;
                wdd8 = wd/8;
                ns = np/wd;
                
                % calculate time-frequency micro-Doppler signature
                %disp('Calculating segments of TF distribution ...')
                for k = 1:ns
                    % disp(strcat('  segment progress: ',num2str(k),'/',num2str(round(ns))))
                    content = string(sprintf('segment progress: %g / %g \n', k, round(ns))) ;
                    app.log_box.Value = [app.log_box.Value; content];
                    
                    sig(1:wd,1) = x(1,(k-1)*wd+1:(k-1)*wd+wd);
                    TMP = app.stft(sig,16);
                    TF2(:,(k-1)*wdd8+1:(k-1)*wdd8+wdd8) = TMP(:,1:8:wd);
                end
                TF = TF2;
                %disp('Calculating shifted segments of TF distribution ...')
                TF1 = zeros(size(TF));
                for k = 1:ns-1
                    % disp(strcat('  shift progress: ',num2str(k),'/',num2str(round(ns-1))))
                    content = string(sprintf('shift progress: %g / %g \n', k, round(ns-1))) ;
                    app.log_box.Value = [app.log_box.Value; content];


                    sig(1:wd,1) = x(1,(k-1)*wd+1+wdd2:(k-1)*wd+wd+wdd2);
                    TMP = app.stft(sig,16);
                    TF1(:,(k-1)*wdd8+1:(k-1)*wdd8+wdd8) = TMP(:,1:8:wd);
                end
                %disp('Removing edge effects ...')
                for k = 1:ns-1
                    TF(:,k*wdd8-8:k*wdd8+8) = ...
                        TF1(:,(k-1)*wdd8+wdd8/2-8:(k-1)*wdd8+wdd8/2+8);
                end
                
                app.log_box.Value=[app.log_box.Value; "draw micro Doppler signature"];
                % display final time-frequency signature
                figure(3)
                colormap(jet(256))
                imagesc([0,app.T],[-F/2000,F/2000],20*log10(fftshift(abs(TF),1)+eps))
                xlabel('Time (s)')
                ylabel('Doppler (kHz)')
                %title('Micro-Doppler Signature of Flapping Wings')
                axis xy
                clim = get(gca,'CLim');
                set(gca,'CLim',clim(2) + [-60 0]);
                set(gca,'FontSize',24)
                colorbar
                drawnow
                
                app.log_box.Value=[app.log_box.Value; "finish!"];

        end

        % Button pushed function: clearlogsButton
        function clearlogsButtonPushed(app, event)
            app.log_box.Value="";
        end

        % Value changed function: nt_box
        function nt_boxValueChanged(app, event)
            app.nt = app.nt_box.Value;
        end

        % Value changed function: nt_box_2
        function nt_box_2ValueChanged(app, event)
            app.lambda = app.nt_box_2.Value;
        end

        % Value changed function: nt_box_3
        function nt_box_3ValueChanged(app, event)
            app.rangeres = app.nt_box_3.Value;
            
        end

        % Value changed function: nt_box_5
        function nt_box_5ValueChanged(app, event)
            app.F0 = app.nt_box_5.Value;
            
        end

        % Value changed function: nt_box_6
        function nt_box_6ValueChanged(app, event)
            app.V = app.nt_box_6.Value;
            
        end

        % Value changed function: nt_box_7
        function nt_box_7ValueChanged(app, event)
            app.A1 = app.nt_box_7.Value;
            
        end

        % Value changed function: nt_box_8
        function nt_box_8ValueChanged(app, event)
            app.ksi10 = app.nt_box_8.Value;
            
        end

        % Value changed function: nt_box_9
        function nt_box_9ValueChanged(app, event)
            app.L1 = app.nt_box_9.Value;
            
        end

        % Value changed function: radarloc1_box
        function radarloc1_boxValueChanged(app, event)
            % value = app.radarloc1_box.Value;
             app.updata_radarloc()
        end

        % Value changed function: radarloc2_box
        function radarloc2_boxValueChanged(app, event)
            % value = app.radarloc2_box.Value;
            app.updata_radarloc()
        end

        % Value changed function: radarloc3_box
        function radarloc3_boxValueChanged(app, event)
            % value = app.radarloc3_box.Value;
            app.updata_radarloc()
        end

        % Value changed function: nt_box_10
        function nt_box_10ValueChanged(app, event)
            app.A2 = app.nt_box_10.Value;
            
        end

        % Value changed function: nt_box_11
        function nt_box_11ValueChanged(app, event)
            app.ksi20 = app.nt_box_11.Value;
            
            
        end

        % Value changed function: nt_box_12
        function nt_box_12ValueChanged(app, event)
            app.L2 = app.nt_box_12.Value;
            
        end

        % Value changed function: nt_box_13
        function nt_box_13ValueChanged(app, event)
            app.C2 = app.nt_box_13.Value;
            
        end

        % Value changed function: nt_box_14
        function nt_box_14ValueChanged(app, event)
            app.Ba = app.nt_box_14.Value;
            
        end

        % Value changed function: nt_box_15
        function nt_box_15ValueChanged(app, event)
            app.Bb = app.nt_box_15.Value;
            
        end

        % Value changed function: nt_box_16
        function nt_box_16ValueChanged(app, event)
            app.Bc = app.nt_box_16.Value;
            
        end

        % Value changed function: nt_box_17
        function nt_box_17ValueChanged(app, event)
            app.Wa = app.nt_box_17.Value;
            
        end

        % Value changed function: nt_box_18
        function nt_box_18ValueChanged(app, event)
            app.Wb = app.nt_box_18.Value;
            
        end

        % Value changed function: nt_box_19
        function nt_box_19ValueChanged(app, event)
            app.Wc = app.nt_box_19.Value;
            
        end

        % Value changed function: vx_box
        function vx_boxValueChanged(app, event)
            app.vx = app.vx_box.Value;
            
        end

        % Value changed function: vy_box
        function vy_boxValueChanged(app, event)
            app.vy = app.vy_box.Value;   
        end

        % Value changed function: vz_box
        function vz_boxValueChanged(app, event)
            app.vz = app.vz_box.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.drawRadarTrackingaFlyingBirdButton = uibutton(app.UIFigure, 'push');
            app.drawRadarTrackingaFlyingBirdButton.ButtonPushedFcn = createCallbackFcn(app, @drawRadarTrackingaFlyingBirdButtonPushed, true);
            app.drawRadarTrackingaFlyingBirdButton.Text = 'draw Radar Tracking  a Flying Bird';
            app.drawRangeProfilesButton = uibutton(app.UIFigure, 'push');
            app.drawRangeProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @drawRangeProfilesButtonPushed, true);
            app.drawRangeProfilesButton.Text = 'draw Range Profiles';
            app.drawmicroDopplersignatureButton = uibutton(app.UIFigure, 'push');
            app.drawmicroDopplersignatureButton.ButtonPushedFcn = createCallbackFcn(app, @drawmicroDopplersignatureButtonPushed, true);
            app.drawmicroDopplersignatureButton.Text = 'draw micro-Doppler signature';
            app.clearlogsButton = uibutton(app.UIFigure, 'push');
            app.clearlogsButton.ButtonPushedFcn = createCallbackFcn(app, @clearlogsButtonPushed, true);
            app.clearlogsButton.Text = 'clear logs';
            app.timedurationLabel = uilabel(app.UIFigure);
            app.timedurationLabel.Text = 'time duration:';
            app.T_box = uispinner(app.UIFigure);
            app.T_box.Limits = [0 Inf];
            app.T_box.ValueDisplayFormat = '%.0f';
            app.T_box.ValueChangedFcn = createCallbackFcn(app, @T_boxValueChanged, true);
            app.T_box.Value = 1;
            app.RealtimelogsLabel = uilabel(app.UIFigure);
            app.RealtimelogsLabel.Text = 'Real-time logs:';
            app.log_box = uitextarea(app.UIFigure);
            app.log_box.Editable = 'off';
            app.log_box.Value = {'Waiting...'};
            app.timedurationLabel_2 = uilabel(app.UIFigure);
            app.timedurationLabel_2.Text = 'number of samples:';
            app.nt_box = uispinner(app.UIFigure);
            app.nt_box.Limits = [0 Inf];
            app.nt_box.ValueDisplayFormat = '%.0f';
            app.nt_box.ValueChangedFcn = createCallbackFcn(app, @nt_boxValueChanged, true);
            app.nt_box.Value = 15000;
            app.timedurationLabel_3 = uilabel(app.UIFigure);
            app.timedurationLabel_3.Text = 'wavelength';
            app.nt_box_2 = uispinner(app.UIFigure);
            app.nt_box_2.Limits = [0 Inf];
            app.nt_box_2.ValueDisplayFormat = '%.15g';
            app.nt_box_2.ValueChangedFcn = createCallbackFcn(app, @nt_box_2ValueChanged, true);
            app.nt_box_2.Value = 0.0032;
            app.timedurationLabel_4 = uilabel(app.UIFigure);
            app.timedurationLabel_4.Text = 'range resolution';
            app.nt_box_3 = uispinner(app.UIFigure);
            app.nt_box_3.Limits = [0 Inf];
            app.nt_box_3.ValueDisplayFormat = '%.15g';
            app.nt_box_3.ValueChangedFcn = createCallbackFcn(app, @nt_box_3ValueChanged, true);
            app.nt_box_3.Value = 0.05;
            app.timedurationLabel_6 = uilabel(app.UIFigure);
            app.timedurationLabel_6.Text = 'flapping frequency';
            app.nt_box_5 = uispinner(app.UIFigure);
            app.nt_box_5.Limits = [0 Inf];
            app.nt_box_5.ValueDisplayFormat = '%.15g';
            app.nt_box_5.ValueChangedFcn = createCallbackFcn(app, @nt_box_5ValueChanged, true);
            app.nt_box_5.Value = 5;
            app.timedurationLabel_7 = uilabel(app.UIFigure);
            app.timedurationLabel_7.Text = 'forward translation velocity';
            app.nt_box_6 = uispinner(app.UIFigure);
            app.nt_box_6.Limits = [0 Inf];
            app.nt_box_6.ValueDisplayFormat = '%.15g';
            app.nt_box_6.ValueChangedFcn = createCallbackFcn(app, @nt_box_6ValueChanged, true);
            app.timedurationLabel_8 = uilabel(app.UIFigure);
            app.timedurationLabel_8.Text = 'flapping angel amplitude 1';
            app.nt_box_7 = uispinner(app.UIFigure);
            app.nt_box_7.Limits = [0 Inf];
            app.nt_box_7.ValueDisplayFormat = '%.15g';
            app.nt_box_7.ValueChangedFcn = createCallbackFcn(app, @nt_box_7ValueChanged, true);
            app.nt_box_7.Value = 40;
            app.timedurationLabel_9 = uilabel(app.UIFigure);
            app.timedurationLabel_9.Text = 'lag flapping angle 1';
            app.nt_box_8 = uispinner(app.UIFigure);
            app.nt_box_8.Limits = [0 Inf];
            app.nt_box_8.ValueDisplayFormat = '%.15g';
            app.nt_box_8.ValueChangedFcn = createCallbackFcn(app, @nt_box_8ValueChanged, true);
            app.nt_box_8.Value = 15;
            app.timedurationLabel_10 = uilabel(app.UIFigure);
            app.timedurationLabel_10.Text = 'length of segment 1';
            app.nt_box_9 = uispinner(app.UIFigure);
            app.nt_box_9.Limits = [0 Inf];
            app.nt_box_9.ValueDisplayFormat = '%.15g';
            app.nt_box_9.ValueChangedFcn = createCallbackFcn(app, @nt_box_9ValueChanged, true);
            app.nt_box_9.Value = 0.5;
            app.timedurationLabel_11 = uilabel(app.UIFigure);
            app.timedurationLabel_11.Text = 'flapping angle amplitude 2';
            app.nt_box_10 = uispinner(app.UIFigure);
            app.nt_box_10.Limits = [0 Inf];
            app.nt_box_10.ValueDisplayFormat = '%.15g';
            app.nt_box_10.ValueChangedFcn = createCallbackFcn(app, @nt_box_10ValueChanged, true);
            app.nt_box_10.Value = 30;
            app.timedurationLabel_12 = uilabel(app.UIFigure);
            app.timedurationLabel_12.Text = 'lag flapping angle 2';
            app.nt_box_11 = uispinner(app.UIFigure);
            app.nt_box_11.Limits = [0 Inf];
            app.nt_box_11.ValueDisplayFormat = '%.15g';
            app.nt_box_11.ValueChangedFcn = createCallbackFcn(app, @nt_box_11ValueChanged, true);
            app.nt_box_11.Value = 40;
            app.timedurationLabel_13 = uilabel(app.UIFigure);
            app.timedurationLabel_13.Text = 'length of segment 2';
            app.nt_box_12 = uispinner(app.UIFigure);
            app.nt_box_12.Limits = [0 Inf];
            app.nt_box_12.ValueDisplayFormat = '%.15g';
            app.nt_box_12.ValueChangedFcn = createCallbackFcn(app, @nt_box_12ValueChanged, true);
            app.nt_box_12.Value = 0.5;
            app.timedurationLabel_14 = uilabel(app.UIFigure);
            app.timedurationLabel_14.Text = 'twisting angle amplitude 2 ';
            app.nt_box_13 = uispinner(app.UIFigure);
            app.nt_box_13.Limits = [0 Inf];
            app.nt_box_13.ValueDisplayFormat = '%.15g';
            app.nt_box_13.ValueChangedFcn = createCallbackFcn(app, @nt_box_13ValueChanged, true);
            app.nt_box_13.Value = 20;
            app.timedurationLabel_15 = uilabel(app.UIFigure);
            app.timedurationLabel_15.Text = 'Ba';
            app.nt_box_14 = uispinner(app.UIFigure);
            app.nt_box_14.Limits = [0 Inf];
            app.nt_box_14.ValueDisplayFormat = '%.15g';
            app.nt_box_14.ValueChangedFcn = createCallbackFcn(app, @nt_box_14ValueChanged, true);
            app.nt_box_14.Value = 0.1;
            app.timedurationLabel_16 = uilabel(app.UIFigure);
            app.timedurationLabel_16.Text = 'Bb';
            app.nt_box_15 = uispinner(app.UIFigure);
            app.nt_box_15.Limits = [0 Inf];
            app.nt_box_15.ValueDisplayFormat = '%.15g';
            app.nt_box_15.ValueChangedFcn = createCallbackFcn(app, @nt_box_15ValueChanged, true);
            app.nt_box_15.Value = 0.1;
            app.timedurationLabel_17 = uilabel(app.UIFigure);
            app.timedurationLabel_17.Text = 'Bc';
            app.nt_box_16 = uispinner(app.UIFigure);
            app.nt_box_16.Limits = [0 Inf];
            app.nt_box_16.ValueDisplayFormat = '%.15g';
            app.nt_box_16.ValueChangedFcn = createCallbackFcn(app, @nt_box_16ValueChanged, true);
            app.nt_box_16.Value = 1;
            app.timedurationLabel_18 = uilabel(app.UIFigure);
            app.timedurationLabel_18.Text = 'Wa';
            app.nt_box_17 = uispinner(app.UIFigure);
            app.nt_box_17.Limits = [0 Inf];
            app.nt_box_17.ValueDisplayFormat = '%.15g';
            app.nt_box_17.ValueChangedFcn = createCallbackFcn(app, @nt_box_17ValueChanged, true);
            app.nt_box_17.Value = 0.05;
            app.timedurationLabel_19 = uilabel(app.UIFigure);
            app.timedurationLabel_19.Text = 'Wb';
            app.nt_box_18 = uispinner(app.UIFigure);
            app.nt_box_18.Limits = [0 Inf];
            app.nt_box_18.ValueDisplayFormat = '%.15g';
            app.nt_box_18.ValueChangedFcn = createCallbackFcn(app, @nt_box_18ValueChanged, true);
            app.nt_box_18.Value = 0.05;
            app.timedurationLabel_20 = uilabel(app.UIFigure);
            app.timedurationLabel_20.Text = 'Wc';
            app.nt_box_19 = uispinner(app.UIFigure);
            app.nt_box_19.Limits = [0 Inf];
            app.nt_box_19.ValueDisplayFormat = '%.15g';
            app.nt_box_19.ValueChangedFcn = createCallbackFcn(app, @nt_box_19ValueChanged, true);
            app.nt_box_19.Value = 0.25;
            app.radarloc2_box = uispinner(app.UIFigure);
            app.radarloc2_box.ValueDisplayFormat = '%.0f';
            app.radarloc2_box.ValueChangedFcn = createCallbackFcn(app, @radarloc2_boxValueChanged, true);
            app.radarloc3_box = uispinner(app.UIFigure);
            app.radarloc3_box.ValueDisplayFormat = '%.0f';
            app.radarloc3_box.ValueChangedFcn = createCallbackFcn(app, @radarloc3_boxValueChanged, true);
            app.radarloc3_box.Value = -10;
            app.betaelevationangleLabel = uilabel(app.UIFigure);
            app.betaelevationangleLabel.Text = 'radar location:';
            app.radarloc1_box = uispinner(app.UIFigure);
            app.radarloc1_box.ValueDisplayFormat = '%.0f';
            app.radarloc1_box.ValueChangedFcn = createCallbackFcn(app, @radarloc1_boxValueChanged, true);
            app.radarloc1_box.Value = 20;
            app.vy_box = uispinner(app.UIFigure);
            app.vy_box.ValueDisplayFormat = '%.0f';
            app.vy_box.ValueChangedFcn = createCallbackFcn(app, @vy_boxValueChanged, true);
            app.vz_box = uispinner(app.UIFigure);
            app.vz_box.ValueDisplayFormat = '%.0f';
            app.vz_box.ValueChangedFcn = createCallbackFcn(app, @vz_boxValueChanged, true);
            app.betaelevationangleLabel_2 = uilabel(app.UIFigure);
            app.betaelevationangleLabel_2.Text = 'velocity';
            app.vx_box = uispinner(app.UIFigure);
            app.vx_box.ValueDisplayFormat = '%.0f';
            app.vx_box.ValueChangedFcn = createCallbackFcn(app, @vx_boxValueChanged, true);
            app.vx_box.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updated_bird_simu_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end