classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DroneRadarDetectionSimulatorUIFigure  matlab.ui.Figure
        fr6_box                         matlab.ui.control.Spinner
        fr5_box                         matlab.ui.control.Spinner
        drawHERMlinesButton             matlab.ui.control.Button
        drawspectrogramButton           matlab.ui.control.Button
        clearlogsButton                 matlab.ui.control.Button
        log_box                         matlab.ui.control.TextArea
        RealtimelogsLabel               matlab.ui.control.Label
        N_box                           matlab.ui.control.Spinner
        NumberofrotorsLabel_2           matlab.ui.control.Label
        drawDopplerspectrumButton       matlab.ui.control.Button
        samplerate_box                  matlab.ui.control.Spinner
        betaelevationangleLabel_4       matlab.ui.control.Label
        R_box                           matlab.ui.control.Spinner
        betaelevationangleLabel_3       matlab.ui.control.Label
        fr4_box                         matlab.ui.control.Spinner
        fr3_box                         matlab.ui.control.Spinner
        fr2_box                         matlab.ui.control.Spinner
        fr1_box                         matlab.ui.control.Spinner
        betaelevationangleLabel_2       matlab.ui.control.Label
        beta_box                        matlab.ui.control.Spinner
        betaelevationangleLabel         matlab.ui.control.Label
        VariablesLabel                  matlab.ui.control.Label
        v_box                           matlab.ui.control.Spinner
        vSUAVvelocityLabel              matlab.ui.control.Label
        fc_box                          matlab.ui.control.Spinner
        fccarrierfreqinGhzLabel         matlab.ui.control.Label
        d1_box                          matlab.ui.control.Spinner
        d1dfromroottotipSpinner_2Label  matlab.ui.control.Label
        L2_box                          matlab.ui.control.Spinner
        L2baldetipLabel                 matlab.ui.control.Label
        L1_box                          matlab.ui.control.Spinner
        L1balderootSpinnerLabel         matlab.ui.control.Label
        NR_box                          matlab.ui.control.Spinner
        NumberofrotorsLabel             matlab.ui.control.Label
        NSample_box                     matlab.ui.control.Spinner
        NumberofsamplesSpinnerLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        % CONSTANT
        i=sqrt(-1);
        theta=0;
        c=2.99792458e8;
        % Global Varable
        NSample = 10000; % Number of samples, user input
        NR=4; % Number of rotors, user input
        N = 2; % Number of blades, user input
        L1 = 0.0; % Blade root L1 < L2, user input
        L2 = 0.13; % Blade tip, user input
        d1=0.41;% user input
        v = 0; % SUAV velocity, user input
        %v=[linspace(40,40,NSample/4) linspace(50,50,NSample/4) linspace(60,60,NSample/4) linspace(70,70,NSample/4)];
        fc = 94e9; % carrer freq, user input
        beta = 0*pi/180; % elevation angle(degree): 0 - 90, user input
        fr = [150 43 89 74]; % rotation rate: revolution per second, user input
        R = 100; % range (m), user input
        samplerate = 200000; % Sampling Rate: Number of sampling per second, user input
        Sigma = 1;
        lambda = 2.99792458e8/94e9;
        dt=1/200000;
        T = 10000/200000;
    end
    
    methods (Access = public)

        function update_fr(app)
            if app.NR==2
                app.fr = [app.fr1_box.Value app.fr2_box.Value];
            elseif app.NR==3
                app.fr = [app.fr1_box.Value app.fr2_box.Value app.fr3_box.Value];
            elseif app.NR==4
                app.fr = [app.fr1_box.Value app.fr2_box.Value app.fr3_box.Value app.fr4_box.Value];
            elseif app.NR==5
                app.fr = [app.fr1_box.Value app.fr2_box.Value app.fr3_box.Value app.fr4_box.Value app.fr5_box.Value];
            elseif app.NR==6
                app.fr = [app.fr1_box.Value app.fr2_box.Value app.fr3_box.Value app.fr4_box.Value app.fr5_box.Value app.fr6_box.Value];
            end
            Vtip = app.L2.*app.fr*2*pi;
            content = string(sprintf('Tip Velocity: %g (m/s)\n', Vtip)) + string(sprintf('Maximum Doppler: %g (kHz)\n',(cos(app.beta)*2*app.fc*Vtip/app.c)/1000));
            app.log_box.Value=content;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.update_fr();
        end

        % Button pushed function: clearlogsButton
        function ButtonPushed(app, event)
            app.log_box.Value="";
        end

        % Button pushed function: drawDopplerspectrumButton
        function draw_return_signal(app, event)
            Vr = complex(zeros(1, app.NSample));
            for m=1:app.NSample
                t=app.dt*(m-1);
                summ=0;
                for qrn=1:app.NR
                    for n=1:app.N
                        nn=n-1;
                        Phi = (4*pi/app.lambda)*(sqrt((app.R+(app.v*t))^2+app.d1^2-(2*(app.R+(app.v*t))*app.d1*cos((app.theta+(360/app.NR*qrn))*pi/180)))+((app.L1+app.L2)/2)*cos(app.beta)* ...
           sin(2*pi.*app.fr(qrn)*t+(2*pi*nn)/app.N));
                        AL = app.Sigma*(app.L2-app.L1)*exp(app.i*(2*pi*app.fc*t-Phi));
                        X = (4*pi/app.lambda)*((app.L2-app.L1)/2)*cos(app.beta)*sin(2*pi.*app.fr(qrn)*t+(2*pi*nn)/app.N);
                        summ = summ+(AL*sinc(X/pi));
                    end
                end
                Phi0 = (4*pi/app.lambda)*(sqrt((app.R+(app.v*t))^2+app.d1^2-(2*(app.R+(app.v*t))*app.d1*cos((app.theta+(90*qrn))*pi/180)))+app.v*t+((app.L1)/2)*cos(app.beta)* ...
           sin(2*pi*0*t+(2*pi*nn)/app.N));
                AL0 = app.Sigma*(0.200)*exp(app.i*(2*pi*app.fc*t-Phi0));
                X0 = (4*pi/app.lambda)*((app.L1)/2)*cos(app.beta)*sin(2*pi*0*t+(2*pi*nn)/app.N);
                summ = summ+(AL0*sinc(X0/pi));
                Vr(m) = summ;
            end
            FV = abs(fftshift(fft(Vr)));

            freq = -app.samplerate/2:app.samplerate/app.NSample:app.samplerate/2-app.samplerate/app.NSample;
            figure(1);
            plot(-(freq*0.0032)/2,FV,'b');
        end

        % Value changed function: L1_box
        function L1_boxValueChanged(app, event)
            value = app.L1_box.Value;
            if value >= app.L2_box.Value
                msgbox("L1 cannot bigger than L2!");
                app.L1_box.Value=0;
            end
            app.L1=app.L1_box.Value;
        end

        % Value changed function: NSample_box
        function NSample_boxValueChanged(app, event)
            app.NSample = app.NSample_box.Value;
            app.T = app.NSample/app.samplerate;
        end

        % Value changed function: NR_box
        function NR_boxValueChanged(app, event)
            app.NR = app.NR_box.Value;
            app.ButtonPushed();
            if app.NR==2
                app.fr3_box.Enable="off";
                app.fr4_box.Enable='off';
                app.fr5_box.Enable='off';
                app.fr6_box.Enable='off';
            elseif app.NR==3
                app.fr3_box.Enable="on";
                app.fr4_box.Enable='off';
                app.fr5_box.Enable='off';
                app.fr6_box.Enable='off';
            elseif app.NR==4
                app.fr3_box.Enable="on";
                app.fr4_box.Enable='on';
                app.fr5_box.Enable='off';
                app.fr6_box.Enable='off';
            elseif app.NR==5
                app.fr3_box.Enable="on";
                app.fr4_box.Enable='on';
                app.fr5_box.Enable='on';
                app.fr6_box.Enable='off';
            elseif app.NR==6
                app.fr3_box.Enable="on";
                app.fr4_box.Enable='on';
                app.fr5_box.Enable='on';
                app.fr6_box.Enable='on';
            end
            app.update_fr();
        end

        % Value changed function: N_box
        function N_boxValueChanged(app, event)
            app.N = app.N_box.Value;
            
        end

        % Value changed function: L2_box
        function L2_boxValueChanged(app, event)
            app.L2 = app.L2_box.Value;
            
        end

        % Value changed function: d1_box
        function d1_boxValueChanged(app, event)
            app.d1 = app.d1_box.Value;
            
        end

        % Value changed function: v_box
        function v_boxValueChanged(app, event)
            app.v = app.v_box.Value;
            
        end

        % Value changed function: fc_box
        function fc_boxValueChanged(app, event)
            app.fc = app.fc_box.Value*1000000000;
            app.lambda = app.c/app.fc;
        end

        % Value changed function: beta_box
        function beta_boxValueChanged(app, event)
            app.beta = app.beta_box.Value*pi/180;
            
        end

        % Value changed function: fr1_box
        function fr1_boxValueChanged(app, event)
            app.update_fr();
        end

        % Value changed function: fr2_box
        function fr2_boxValueChanged(app, event)
            app.update_fr();
        end

        % Value changed function: fr3_box
        function fr3_boxValueChanged(app, event)
            app.update_fr();
        end

        % Value changed function: fr4_box
        function fr4_boxValueChanged(app, event)
            app.update_fr();
        end

        % Value changed function: R_box
        function R_boxValueChanged(app, event)
            app.R = app.R_box.Value;
            
        end

        % Value changed function: samplerate_box
        function samplerate_boxValueChanged(app, event)
            app.samplerate = app.samplerate_box.Value;
            app.dt=1/app.samplerate;
            app.T = app.NSample/app.samplerate;
        end

        % Button pushed function: drawspectrogramButton
        function drawspectrogramButtonPushed(app, event)
            for m=1:app.NSample
                t=app.dt*(m-1);
                summ=0;
                for qrn=1:app.NR
                    for n=1:app.N
                        nn=n-1;
                        Phi = (4*pi/app.lambda)*(sqrt((app.R+(app.v*t))^2+app.d1^2-(2*(app.R+(app.v*t))*app.d1*cos((app.theta+(360/app.NR*qrn))*pi/180)))+((app.L1+app.L2)/2)*cos(app.beta)* ...
           sin(2*pi.*app.fr(qrn)*t+(2*pi*nn)/app.N));
                        AL = app.Sigma*(app.L2-app.L1)*exp(app.i*(2*pi*app.fc*t-Phi));
                        X = (4*pi/app.lambda)*((app.L2-app.L1)/2)*cos(app.beta)*sin(2*pi.*app.fr(qrn)*t+(2*pi*nn)/app.N);
                        summ = summ+(AL*sinc(X/pi));
                    end
                end
                Phi0 = (4*pi/app.lambda)*(sqrt((app.R+(app.v*t))^2+app.d1^2-(2*(app.R+(app.v*t))*app.d1*cos((app.theta+(90*qrn))*pi/180)))+app.v*t+((app.L1)/2)*cos(app.beta)* ...
           sin(2*pi*0*t+(2*pi*nn)/app.N));
                AL0 = app.Sigma*(0.200)*exp(app.i*(2*pi*app.fc*t-Phi0));
                X0 = (4*pi/app.lambda)*((app.L1)/2)*cos(app.beta)*sin(2*pi*0*t+(2*pi*nn)/app.N);
                summ = summ+(AL0*sinc(X0/pi));
                Vr(m) = summ;
            end
            x = Vr;
            np = length(Vr);
            wd = 64;
            ns=np-(wd-1);
            TF=zeros(wd*2,ns);
            win=gausswin(wd,4);
            for k=1:ns
                tmp=win' .* x(k:k+wd-1); %windowing and overlapping
                tmp(length(tmp)+1:length(tmp)*2)=0; %zero padding
                tmp=fft(tmp);
                TF(:,k)=tmp(1:length(tmp));
            end
            figure(2)
            colormap(jet(256))
            imagesc([0,app.T],[(-1/(2*(1/app.samplerate))/1e3),(1/(2*(1/app.samplerate))/1e3)],20*log10(fftshift(abs(TF),1)+eps))
            xlabel('Time (s)')
            ylabel('Doppler (KHz)')
            title(num2str(app.fc_box.Value)+" GHz")
            axis xy
            clim = get(gca,'CLim');
            set(gca,'CLim',clim(2) + [-80 0]);
            cc=colorbar;
            ylabel(cc,'Relative Signal strength (dB)')
            set(gca,'FontWeight','Bold','FontSize',30)
            set(gcf,'color','white')
            drawnow
        end

        % Button pushed function: drawHERMlinesButton
        function drawHERMlinesButtonPushed(app, event)
            for m=1:app.NSample
                t=app.dt*(m-1);
                summ=0;
                for qrn=1:app.NR
                    for n=1:app.N
                        nn=n-1;
                        Phi = (4*pi/app.lambda)*(sqrt((app.R+(app.v*t))^2+app.d1^2-(2*(app.R+(app.v*t))*app.d1*cos((app.theta+(360/app.NR*qrn))*pi/180)))+((app.L1+app.L2)/2)*cos(app.beta)* ...
           sin(2*pi.*app.fr(qrn)*t+(2*pi*nn)/app.N));
                        AL = app.Sigma*(app.L2-app.L1)*exp(app.i*(2*pi*app.fc*t-Phi));
                        X = (4*pi/app.lambda)*((app.L2-app.L1)/2)*cos(app.beta)*sin(2*pi.*app.fr(qrn)*t+(2*pi*nn)/app.N);
                        summ = summ+(AL*sinc(X/pi));
                    end
                end
                Phi0 = (4*pi/app.lambda)*(sqrt((app.R+(app.v*t))^2+app.d1^2-(2*(app.R+(app.v*t))*app.d1*cos((app.theta+(90*qrn))*pi/180)))+app.v*t+((app.L1)/2)*cos(app.beta)* ...
           sin(2*pi*0*t+(2*pi*nn)/app.N));
                AL0 = app.Sigma*(0.200)*exp(app.i*(2*pi*app.fc*t-Phi0));
                X0 = (4*pi/app.lambda)*((app.L1)/2)*cos(app.beta)*sin(2*pi*0*t+(2*pi*nn)/app.N);
                summ = summ+(AL0*sinc(X0/pi));
                Vr(m) = summ;
            end
            FV = abs(fftshift(fft(Vr)));
            figure(3)
            colormap(jet(256))
            imagesc([0,app.T],[-app.samplerate/2,app.samplerate/2],20*log10(FV'))
            xlim([0,app.T])
            xlabel('Time (s)')
            ylabel('Doppler (Hz)')
            title('HElicopter Rotor Modulation (HERM) lines')
            axis xy
            clim = get(gca,'CLim');
            set(gca,'CLim',clim(2) + [-50 0]);
            cc=colorbar;
            ylabel(cc,'Signal strength (dB)')
            set(gca,'FontSize',10)
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DroneRadarDetectionSimulatorUIFigure = uifigure('Visible', 'off');
            app.DroneRadarDetectionSimulatorUIFigure.Name = 'Drone Radar Detection Simulator';
            app.DroneRadarDetectionSimulatorUIFigure.Icon = fullfile(pathToMLAPP, 'radar.png');
            app.NumberofsamplesSpinnerLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.NumberofsamplesSpinnerLabel.Text = 'Number of samples:';
            app.NSample_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.NSample_box.Limits = [0 Inf];
            app.NSample_box.ValueDisplayFormat = '%.0f';
            app.NSample_box.ValueChangedFcn = createCallbackFcn(app, @NSample_boxValueChanged, true);
            app.NSample_box.Value = 10000;
            app.NumberofrotorsLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.NumberofrotorsLabel.Text = 'NR (Number of rotors):';
            app.NR_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.NR_box.Limits = [2 6];
            app.NR_box.ValueDisplayFormat = '%.0f';
            app.NR_box.ValueChangedFcn = createCallbackFcn(app, @NR_boxValueChanged, true);
            app.NR_box.Value = 4;
            app.L1balderootSpinnerLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.L1balderootSpinnerLabel.Text = 'L1 (balde root):';
            app.L1_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.L1_box.Step = 0.01;
            app.L1_box.Limits = [0 1];
            app.L1_box.ValueDisplayFormat = '%.2f';
            app.L1_box.ValueChangedFcn = createCallbackFcn(app, @L1_boxValueChanged, true);
            app.L2baldetipLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.L2baldetipLabel.Text = 'L2 (balde tip):';
            app.L2_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.L2_box.Step = 0.01;
            app.L2_box.Limits = [0 1];
            app.L2_box.ValueDisplayFormat = '%.2f';
            app.L2_box.ValueChangedFcn = createCallbackFcn(app, @L2_boxValueChanged, true);
            app.L2_box.Value = 0.13;
            app.d1dfromroottotipSpinner_2Label = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.d1dfromroottotipSpinner_2Label.Text = 'd1 (d from root to tip):';
            app.d1_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.d1_box.Step = 0.01;
            app.d1_box.Limits = [0 1];
            app.d1_box.ValueDisplayFormat = '%.2f';
            app.d1_box.ValueChangedFcn = createCallbackFcn(app, @d1_boxValueChanged, true);
            app.d1_box.Value = 0.41;
            app.fccarrierfreqinGhzLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.fccarrierfreqinGhzLabel.Text = 'fc (carrier freq in Ghz):';
            app.fc_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fc_box.Limits = [1 Inf];
            app.fc_box.ValueChangedFcn = createCallbackFcn(app, @fc_boxValueChanged, true);
            app.fc_box.Value = 94;
            app.vSUAVvelocityLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.vSUAVvelocityLabel.Text = 'v (SUAV velocity):';
            app.v_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.v_box.Limits = [0 50];
            app.v_box.ValueDisplayFormat = '%.2f';
            app.v_box.ValueChangedFcn = createCallbackFcn(app, @v_boxValueChanged, true);
            app.VariablesLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.VariablesLabel.Text = 'Variables:';
            app.betaelevationangleLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.betaelevationangleLabel.Text = 'beta (elevation angle °):';
            app.beta_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.beta_box.Step = 0.1;
            app.beta_box.Limits = [0 90];
            app.beta_box.ValueDisplayFormat = '%.1f';
            app.beta_box.ValueChangedFcn = createCallbackFcn(app, @beta_boxValueChanged, true);
            app.betaelevationangleLabel_2 = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.betaelevationangleLabel_2.Text = 'fr (rotation rate):';
            app.fr1_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fr1_box.ValueDisplayFormat = '%.0f';
            app.fr1_box.ValueChangedFcn = createCallbackFcn(app, @fr1_boxValueChanged, true);
            app.fr1_box.Value = 150;
            app.fr2_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fr2_box.ValueDisplayFormat = '%.0f';
            app.fr2_box.ValueChangedFcn = createCallbackFcn(app, @fr2_boxValueChanged, true);
            app.fr2_box.Value = 43;
            app.fr3_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fr3_box.ValueDisplayFormat = '%.0f';
            app.fr3_box.ValueChangedFcn = createCallbackFcn(app, @fr3_boxValueChanged, true);
            app.fr3_box.Value = 89;
            app.fr4_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fr4_box.ValueDisplayFormat = '%.0f';
            app.fr4_box.ValueChangedFcn = createCallbackFcn(app, @fr4_boxValueChanged, true);
            app.fr4_box.Value = 74;
            app.betaelevationangleLabel_3 = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.betaelevationangleLabel_3.Text = 'R (range):';
            app.R_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.R_box.Limits = [0 Inf];
            app.R_box.ValueDisplayFormat = '%.1f';
            app.R_box.ValueChangedFcn = createCallbackFcn(app, @R_boxValueChanged, true);
            app.R_box.Value = 100;
            app.betaelevationangleLabel_4 = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.betaelevationangleLabel_4.Text = 'Sample Rate:';
            app.samplerate_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.samplerate_box.Limits = [0 Inf];
            app.samplerate_box.ValueDisplayFormat = '%.0f';
            app.samplerate_box.ValueChangedFcn = createCallbackFcn(app, @samplerate_boxValueChanged, true);
            app.samplerate_box.Value = 200000;
            app.drawDopplerspectrumButton = uibutton(app.DroneRadarDetectionSimulatorUIFigure, 'push');
            app.drawDopplerspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @draw_return_signal, true);
            app.drawDopplerspectrumButton.Text = 'draw Doppler spectrum';
            app.NumberofrotorsLabel_2 = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.NumberofrotorsLabel_2.Text = 'N (Number of blades):';
            app.N_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.N_box.Limits = [2 4];
            app.N_box.ValueDisplayFormat = '%.0f';
            app.N_box.ValueChangedFcn = createCallbackFcn(app, @N_boxValueChanged, true);
            app.N_box.Value = 2;
            app.RealtimelogsLabel = uilabel(app.DroneRadarDetectionSimulatorUIFigure);
            app.RealtimelogsLabel.Text = 'Real-time logs:';
            app.log_box = uitextarea(app.DroneRadarDetectionSimulatorUIFigure);
            app.log_box.Editable = 'off';
            app.log_box.Value = {'Tip Velocity: 122.522 (m/s)'; 'Tip Velocity: 35.123 (m/s)'; 'Tip Velocity: 72.6965 (m/s)'; 'Tip Velocity: 60.4442 (m/s)'; 'Maximum Doppler: 76.8337 (kHz)'; 'Maximum Doppler: 22.0257 (kHz)'; 'Maximum Doppler: 45.588 (kHz)'; 'Maximum Doppler: 37.9046 (kHz)'};
            app.clearlogsButton = uibutton(app.DroneRadarDetectionSimulatorUIFigure, 'push');
            app.clearlogsButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.clearlogsButton.Text = 'clear logs';
            app.drawspectrogramButton = uibutton(app.DroneRadarDetectionSimulatorUIFigure, 'push');
            app.drawspectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @drawspectrogramButtonPushed, true);
            app.drawspectrogramButton.Text = 'draw spectrogram';
            app.drawHERMlinesButton = uibutton(app.DroneRadarDetectionSimulatorUIFigure, 'push');
            app.drawHERMlinesButton.ButtonPushedFcn = createCallbackFcn(app, @drawHERMlinesButtonPushed, true);
            app.drawHERMlinesButton.Text = 'draw HERM lines';
            app.fr5_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fr5_box.ValueDisplayFormat = '%.0f';
            app.fr5_box.Enable = 'off';
            app.fr5_box.Value = -124;
            app.fr6_box = uispinner(app.DroneRadarDetectionSimulatorUIFigure);
            app.fr6_box.ValueDisplayFormat = '%.0f';
            app.fr6_box.Enable = 'off';
            app.fr6_box.Value = -15;
            % Show the figure after all components are created
            app.DroneRadarDetectionSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DroneRadarDetectionSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DroneRadarDetectionSimulatorUIFigure)
        end
    end
end